classdef PURE_scorecard_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        DataTable                       matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        PURECardTab                     matlab.ui.container.Tab
        UITable4                        matlab.ui.control.Table
        UITable3                        matlab.ui.control.Table
        UITable2                        matlab.ui.control.Table
        Image                           matlab.ui.control.Image
        UIAxes                          matlab.ui.control.UIAxes
        EntertotalnumberofstepsSpinner  matlab.ui.control.Spinner
        EntertotalnumberofstepsLabel    matlab.ui.control.Label
        EnterfilenameEditField          matlab.ui.control.EditField
        EnterfilenameEditFieldLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        table1 % Description
        vars_keep % Description
        filename % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: EnterfilenameEditField
        function EnterfilenameEditFieldValueChanged(app, event)
            % creating table1 of all the data imported from spreadsheet filename 
            app.filename = app.EnterfilenameEditField.Value;
            app.table1 = readtable(app.filename);
            app.vars_keep = ["Task_" , "TaskDescription", "Step_", "StepDescription","P1", "P2", "Consensus" , "Notes"];
            app.UITable.Data = app.table1(:, app.vars_keep);

        end

        % Value changed function: EntertotalnumberofstepsSpinner
        function EntertotalnumberofstepsSpinnerValueChanged(app, event)
            % creating an abstraction table2 of necessary,cleaned data
            value = app.EntertotalnumberofstepsSpinner.Value;
            app.UITable.Data = app.table1(1:value, app.vars_keep);
            app.UITable2.Data  = app.table1(1:value,["TaskDescription","Step_","StepDescription", "Consensus"]);

            % selecting the steps that have a PURE score of 1
            ones = app.table1(app.table1.Consensus == 1,:);
            % creating a bar graph for these values and colouring them green 
            bar(app.UIAxes,ones.Step_,ones.Consensus,"FaceColor","g","EdgeColor","g")
            % colouring the row in the table green
            color = uistyle("BackgroundColor","g");
            addStyle(app.UITable2, color, "row",ones.Step_)
            
            hold(app.UIAxes, "on")

            % repeating process for PURE score of 2
            twos = app.table1(app.table1.Consensus == 2,:);
            bar(app.UIAxes, twos.Step_, twos.Consensus,"FaceColor","#EDB120","EdgeColor","#EDB120")
            color = uistyle("BackgroundColor","#EDB120");
            addStyle(app.UITable2, color, "row",twos.Step_)
            
            hold(app.UIAxes, "on")

            % repeating process for PURE score of 3
            threes = app.table1(app.table1.Consensus == 3,:);
            bar(app.UIAxes, threes.Step_, threes.Consensus,"FaceColor","r","EdgeColor","r")
            color = uistyle("BackgroundColor","r");
            addStyle(app.UITable2, color, "row",threes.Step_)

            % totalling the scores for each individual task 
            [num,name] = findgroups(app.table1.TaskDescription);
            taskTotal = splitapply(@sum,app.table1.Consensus,num);
            % calculating average per step (rounded to nearest integer)
            av_score = round(splitapply(@mean,app.table1.Consensus, num));
            % creating a table that shows each task, its total and its average score
            app.UITable3.Data = table(name,taskTotal,av_score);
            
            %colouring the scores for each task based on their average
            av_ones = find(app.UITable3.Data.av_score == 1);
            color2 = uistyle("BackgroundColor","g");
            addStyle(app.UITable3,color2, "row",av_ones)

            av_twos = find(app.UITable3.Data.av_score == 2);
            color2 = uistyle("BackgroundColor","#EDB120");
            addStyle(app.UITable3,color2, "row",av_twos)
            
            av_threes = find(app.UITable3.Data.av_score == 3);
            color2 = uistyle("BackgroundColor","r");
            addStyle(app.UITable3,color2, "row",av_threes)
           
            % calculating the minimum and maximum PURE scores possible
            minimum = value;
            maximum = value * 3;
            % calculating the actual PURE scores by totalling the score per task
            actual = sum(app.UITable3.Data.taskTotal);
            % displaying this in a table
            total_table = table(minimum,actual, maximum);
            app.UITable4.Data = total_table;


        end

        % Callback function
        function Tasksscoring1CheckBoxValueChanged(app, event)
       
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.EnterfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterfilenameEditFieldLabel.Text = 'Enter file name';
            app.EnterfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterfilenameEditFieldValueChanged, true);
            app.EntertotalnumberofstepsLabel = uilabel(app.UIFigure);
            app.EntertotalnumberofstepsLabel.Text = 'Enter total number of steps';
            app.EntertotalnumberofstepsSpinner = uispinner(app.UIFigure);
            app.EntertotalnumberofstepsSpinner.ValueChangedFcn = createCallbackFcn(app, @EntertotalnumberofstepsSpinnerValueChanged, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataTable = uitab(app.TabGroup);
            app.DataTable.Title = 'Data Table';
            app.UITable = uitable(app.DataTable);
            app.UITable.ColumnName = {'Test #'; 'Task Description'; 'Step #'; 'Step Description'; 'P1'; 'P2'; 'Consensus'; 'Notes'};
            app.UITable.RowName = {};
            app.PURECardTab = uitab(app.TabGroup);
            app.PURECardTab.Title = 'PURE Card';
            app.UIAxes = uiaxes(app.PURECardTab);
            title(app.UIAxes, 'PURE score per Step')
            xlabel(app.UIAxes, 'Step#')
            ylabel(app.UIAxes, 'PURE score')
            zlabel(app.UIAxes, 'Z')
            app.Image = uiimage(app.PURECardTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'PURE key.png');
            app.UITable2 = uitable(app.PURECardTab);
            app.UITable2.ColumnName = {'Task Description'; 'Step Number'; 'Step Description'; 'Consensus'};
            app.UITable2.RowName = {};
            app.UITable3 = uitable(app.PURECardTab);
            app.UITable3.ColumnName = {'Task Description'; 'Total PURE score for task'; 'Average PURE score per step'};
            app.UITable3.RowName = {};
            app.UITable4 = uitable(app.PURECardTab);
            app.UITable4.ColumnName = {'Minimum'; 'PURE Score'; 'Maximum'};
            app.UITable4.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PURE_scorecard_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end